classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        MakeLoadedUR3eMoveButton   matlab.ui.control.Button
        MakeLoadedWidowMoveButton  matlab.ui.control.Button
        plot                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % place your class properties here
        modelWidow % Where I will put the loaded Widow
        modelUR3e % Where I will put the loaded UR3e
        qz = [0     0     0     0     0     0];
        qrWidow = [0	pi/2  -pi/2	0	0	0];
        qrUR3e = [pi/4	pi/2  -pi/2	0	0	0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;

            % Make, save and plot some robots            
            app.modelWidow = WidowX250;
            app.modelWidow.model.base = transl([0.5,0,0]);
            % plot(app.modelWidow,app.qrWidow,'workspace',[-2 2 -2 2 -0.5 2])     
            app.modelWidow.model.plot(app.qrWidow, 'workspace', [-2 2 -2 2 -0.5 2]);
            hold on;         

            app.modelUR3e = UR3e;
            app.modelUR3e.model.base = transl([-0.5,0,0]);
            app.modelUR3e.model.animate(app.qrUR3e);
            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Turn off this load button and enable the move button
            app.MakeLoadedWidowMoveButton.Enable = true;
            app.MakeLoadedUR3eMoveButton.Enable = true;
            
        end

        % Button pushed function: MakeLoadedWidowMoveButton
        function MakeLoadedWidowMoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrWidow,app.qz,20);          
            for robotStepIndex = 1:size(qmatrix,1)

                app.modelWidow.model.animate(qmatrix(robotStepIndex,:));
                drawnow;
                
            end
        end

        % Button pushed function: MakeLoadedUR3eMoveButton
        function MakeLoadedUR3eMoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrUR3e,app.qz,20);          
            for robotStepIndex = 1:size(qmatrix,1)

                app.modelUR3e.model.animate(qmatrix(robotStepIndex,:));
                drawnow;

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.MakeLoadedWidowMoveButton = uibutton(app.figure1, 'push');
            app.MakeLoadedWidowMoveButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedWidowMoveButtonPushed, true);
            app.MakeLoadedWidowMoveButton.Text = 'Make Loaded Widow Move';
            app.MakeLoadedUR3eMoveButton = uibutton(app.figure1, 'push');
            app.MakeLoadedUR3eMoveButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedUR3eMoveButtonPushed, true);
            app.MakeLoadedUR3eMoveButton.Text = 'Make Loaded UR3e Move';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end